classdef maskEditorGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        LoadMenu                      matlab.ui.container.Menu
        LoadMaskMenu                  matlab.ui.container.Menu
        SaveMaskMenu                  matlab.ui.container.Menu
        EditMenu                      matlab.ui.container.Menu
        ExportMenu                    matlab.ui.container.Menu
        RevertMenu                    matlab.ui.container.Menu
        slice                         matlab.ui.control.UIAxes
        ZSlider                       matlab.ui.control.Slider
        ZSpinner                      matlab.ui.control.Spinner
        DIsplayMaskSwitchLabel        matlab.ui.control.Label
        DIsplayMaskSwitch             matlab.ui.control.Switch
        ZsliceLabel                   matlab.ui.control.Label
        ResampleFactorEditFieldLabel  matlab.ui.control.Label
        FacEdit                       matlab.ui.control.NumericEditField
        contoureditorPanel            matlab.ui.container.Panel
        EditSwitch                    matlab.ui.control.Switch
        EditLamp                      matlab.ui.control.Lamp
        EditsliceButton               matlab.ui.control.Button
        SaveMaskButton                matlab.ui.control.Button
        RevertButton                  matlab.ui.control.Button
        contourPanel                  matlab.ui.container.Panel
        DisplayContourSwitchLabel     matlab.ui.control.Label
        DisplayContourSwitch          matlab.ui.control.Switch
        PeriosteumContourButton       matlab.ui.control.Button
        EndosteumContourButton        matlab.ui.control.Button
    end

    % Data Properties
    properties (Access = public)
        sliceim           % slice image
        slicemask       % slice mask plot handles
        slicen          % slice number
        maskeditorObj   % the input maskEditor object
        polygon         % the active polygone for contour editing
        editfig         % figure handle of the slice beeing edited
        editax          % axes handle of the slice beeing edited
    end

    methods (Access = public)
    
        function plotSlice(app)
            switch app.DIsplayMaskSwitch.Value
                case 'On'
                    imagesc(app.slice, double(app.maskeditorObj.sliceC).*app.maskeditorObj.maskC(:,:,app.maskeditorObj.slicen));
                case 'Off'
                    imagesc(app.slice, app.maskeditorObj.sliceC);
            end
            
            axis(app.slice, 'image');
            colormap(app.slice, 'gray');
            % app.slice.YDir = 'normal';
            app.plotContour;
            
        end
        
        function plotContour(app)
            switch app.DisplayContourSwitch.Value
                case 'On'
                    app.maskeditorObj.calcAndSetContourCDslice;
                    hold(app.slice, 'on');
                    plot(app.slice, app.maskeditorObj.fac*app.maskeditorObj.contourCDslice(:,1)-0.5*app.maskeditorObj.fac, app.maskeditorObj.fac*app.maskeditorObj.contourCDslice(:,2)-0.5*app.maskeditorObj.fac, 'g');
                    hold(app.slice, 'off');
                case 'Off'
                    hold(app.slice, 'off');
            end
        end
        
        % slice lines plotters
        function updateXYslicelineX(app)
            set(app.XYslicelinex, 'Visible', 'off');
            app.XYslicelinex = plot(app.slice, [app.sctdataObj.YZslicen app.sctdataObj.YZslicen], [1 app.sctdataObj.size(2)], 'r');
        end
        
        % GUI initialization
        function initGUI(app)
            if isempty(app.maskeditorObj.im)     return;     end
            if isempty(app.maskeditorObj.mask)   return;     end
            
            app.plotSlice;
            app.updateSlider;
            app.updateSpinner;
            app.FacEdit.Value = app.maskeditorObj.fac;
            app.EditSwitch.Value = 'Off';
            app.updateLamp;
        end
        
        function updateSlider(app)
            app.ZSlider.Limits = [1 size(app.maskeditorObj.maskCD, 3)];
            app.ZSlider.MajorTicks = [1 size(app.maskeditorObj.maskCD, 3)];
            app.ZSlider.MajorTickLabels = {'1', num2str(size(app.maskeditorObj.maskCD, 3))};
            app.ZSlider.Value = app.maskeditorObj.slicenD;
        end
        
        function updateSpinner(app)
            app.ZSpinner.Limits = [1 size(app.maskeditorObj.maskCD, 3)];
            app.ZSpinner.Value = app.maskeditorObj.slicenD;
        end
        
        function updateLamp(app)
            switch app.EditSwitch.Value
                case 'Off'
                    app.EditLamp.Color = 'red';
                    app.FacEdit.Editable = 'on';
                    app.PeriosteumContourButton.Enable = 'on';
                    app.EndosteumContourButton.Enable = 'on';
                    app.EditsliceButton.Enable = 'off';
                    app.SaveMaskButton.Enable = 'off';
                    app.RevertButton.Enable = 'off';
                case 'On'
                    app.EditLamp.Color = 'green';
                    app.FacEdit.Editable = 'off';
                    app.PeriosteumContourButton.Enable = 'off';
                    app.EndosteumContourButton.Enable = 'off';
                    app.EditsliceButton.Enable = 'on';
                    app.SaveMaskButton.Enable = 'on';
                    app.RevertButton.Enable = 'on';
            end
        end
        
        function updateMaskCDe(app)
            % if the Edit mode is 'On' takes the mask from the active polygon and updates slice of maskCDe
            edit_value = app.EditSwitch.Value;
            if ~isempty(app.editfig)
                if ishandle(app.editfig) && strcmp(edit_value, 'On')
                    app.maskeditorObj.updateMaskCDe;
                    close(app.editfig);
                end
            end
        end
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, this)
            if nargin < 2
                app.maskeditorObj = maskEditor;
            else
                app.maskeditorObj = this;   % assign input maskEditor instance to public prop
            
                app.initGUI;    
            end
        end

        % Value changed function: ZSlider
        function ZSliderValueChanged(app, event)
            value = round(app.ZSlider.Value);
            app.updateMaskCDe;
            app.maskeditorObj.setSlicenD(value);
            app.maskeditorObj.setSliceC;
            app.plotSlice;
            app.ZSpinner.Value = value;
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            app.maskeditorObj.load;
        end

        % Value changed function: ZSpinner
        function ZSpinnerValueChanged(app, event)
            app.updateMaskCDe;
            value = app.ZSpinner.Value;
            app.maskeditorObj.setSlicenD(value);
            app.maskeditorObj.setSliceC;
            app.plotSlice;
            app.ZSlider.Value = value;
        end

        % Value changed function: DIsplayMaskSwitch
        function DIsplayMaskSwitchValueChanged(app, event)
            value = app.DIsplayMaskSwitch.Value;
            switch value
                case 'On'
                    if isempty(app.maskeditorObj.maskC)
                        fprintf('mask field is empty!\n');
                        app.DIsplayMaskSwitch.Value = 'Off';
                    else
                        app.plotSlice;
                    end
                case 'Off'
                    app.plotSlice;
            end
        end

        % Value changed function: FacEdit
        function FacEditValueChanged(app, event)
            value = app.FacEdit.Value;
            app.maskeditorObj.setFac(value);
            app.updateSlider;
            app.updateSpinner;
            app.plotSlice;
        end

        % Value changed function: EditSwitch
        function EditSwitchValueChanged(app, event)
            value = app.EditSwitch.Value;
            app.updateLamp;
        end

        % Value changed function: DisplayContourSwitch
        function DisplayContourSwitchValueChanged(app, event)
            value = app.DisplayContourSwitch.Value;
            switch value
                case 'On'
                    if isempty(app.maskeditorObj.contourCDslice)
                        fprintf('contour slice is empty!\n');
                        app.DisplayContourSwitch.Value = 'Off';
                    else
                        app.plotContour;
                    end
                case 'Off'
                    app.plotSlice;
            end
        end

        % Callback function
        function PeriosteumContourButtonValueChanged(app, event)
            app.maskeditorObj.setPeriosteumcontour;
        end

        % Callback function
        function EndosteumContourButtonValueChanged(app, event)
            app.maskeditorObj.setEndosteumcontour;
        end

        % Button pushed function: PeriosteumContourButton
        function PeriosteumContourButtonPushed(app, event)
            app.maskeditorObj.setPeriosteumcontour;
        end

        % Button pushed function: EndosteumContourButton
        function EndosteumContourButtonPushed(app, event)
            app.maskeditorObj.setEndosteumcontour;
        end

        % Button pushed function: EditsliceButton
        function EditsliceButtonPushed(app, event)
            [app.editfig, app.editax] = app.maskeditorObj.editContourCDslice;
            % title(sprintf('slice %i', app.maskeditorObj.slicenD), 'FontWeight','Normal', 'FontSize', 10);
        end

        % Button pushed function: SaveMaskButton
        function SaveMaskButtonPushed(app, event)
            app.maskeditorObj.exportMask;
        end

        % Button pushed function: RevertButton
        function RevertButtonPushed(app, event)
            app.maskeditorObj.initMaskCDe;
        end

        % Menu selected function: LoadMaskMenu
        function LoadMaskMenuSelected(app, event)
            app.maskeditorObj.importMask;
        end

        % Menu selected function: SaveMaskMenu
        function SaveMaskMenuSelected(app, event)
            app.maskeditorObj.updateMaskCD;
            app.maskeditorObj.exportMask;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Accelerator = 'o';
            app.LoadMenu.Text = 'Load';
            app.LoadMaskMenu = uimenu(app.FileMenu);
            app.LoadMaskMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMaskMenuSelected, true);
            app.LoadMaskMenu.Text = 'Load Mask';
            app.SaveMaskMenu = uimenu(app.FileMenu);
            app.SaveMaskMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMaskMenuSelected, true);
            app.SaveMaskMenu.Text = 'Save Mask';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.ExportMenu = uimenu(app.EditMenu);
            app.ExportMenu.Text = 'Export';
            app.RevertMenu = uimenu(app.EditMenu);
            app.RevertMenu.Text = 'Revert';
            app.slice = uiaxes(app.UIFigure);
            xlabel(app.slice, 'X (pixels)')
            ylabel(app.slice, 'Y (pixels)')
            app.ZSlider = uislider(app.UIFigure);
            app.ZSlider.Limits = [1 100];
            app.ZSlider.MajorTicks = [1 11 21 31 41 51 61 71 81 100];
            app.ZSlider.MajorTickLabels = {'1', '', '', '', '', '', '', '', '', '100'};
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliderValueChanged, true);
            app.ZSlider.Value = 1;
            app.ZSpinner = uispinner(app.UIFigure);
            app.ZSpinner.Limits = [1 Inf];
            app.ZSpinner.RoundFractionalValues = 'on';
            app.ZSpinner.ValueDisplayFormat = '%.0f';
            app.ZSpinner.ValueChangedFcn = createCallbackFcn(app, @ZSpinnerValueChanged, true);
            app.ZSpinner.Value = 1;
            app.DIsplayMaskSwitchLabel = uilabel(app.UIFigure);
            app.DIsplayMaskSwitchLabel.Text = 'DIsplay Mask';
            app.DIsplayMaskSwitch = uiswitch(app.UIFigure, 'slider');
            app.DIsplayMaskSwitch.ValueChangedFcn = createCallbackFcn(app, @DIsplayMaskSwitchValueChanged, true);
            app.ZsliceLabel = uilabel(app.UIFigure);
            app.ZsliceLabel.Text = 'Z-slice';
            app.ResampleFactorEditFieldLabel = uilabel(app.UIFigure);
            app.ResampleFactorEditFieldLabel.Text = 'Resample Factor';
            app.FacEdit = uieditfield(app.UIFigure, 'numeric');
            app.FacEdit.Limits = [1 40];
            app.FacEdit.ValueDisplayFormat = '%.0f';
            app.FacEdit.ValueChangedFcn = createCallbackFcn(app, @FacEditValueChanged, true);
            app.FacEdit.Value = 1;
            app.contoureditorPanel = uipanel(app.UIFigure);
            app.contoureditorPanel.Title = 'contour editor';
            app.EditSwitch = uiswitch(app.contoureditorPanel, 'slider');
            app.EditSwitch.ValueChangedFcn = createCallbackFcn(app, @EditSwitchValueChanged, true);
            app.EditLamp = uilamp(app.contoureditorPanel);
            app.EditsliceButton = uibutton(app.contoureditorPanel, 'push');
            app.EditsliceButton.ButtonPushedFcn = createCallbackFcn(app, @EditsliceButtonPushed, true);
            app.EditsliceButton.Text = 'Edit slice';
            app.SaveMaskButton = uibutton(app.contoureditorPanel, 'push');
            app.SaveMaskButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMaskButtonPushed, true);
            app.SaveMaskButton.Text = 'Save Mask';
            app.RevertButton = uibutton(app.contoureditorPanel, 'push');
            app.RevertButton.ButtonPushedFcn = createCallbackFcn(app, @RevertButtonPushed, true);
            app.RevertButton.Text = 'Revert';
            app.contourPanel = uipanel(app.UIFigure);
            app.contourPanel.Title = 'contour';
            app.DisplayContourSwitchLabel = uilabel(app.contourPanel);
            app.DisplayContourSwitchLabel.Text = 'Display Contour';
            app.DisplayContourSwitch = uiswitch(app.contourPanel, 'slider');
            app.DisplayContourSwitch.ValueChangedFcn = createCallbackFcn(app, @DisplayContourSwitchValueChanged, true);
            app.PeriosteumContourButton = uibutton(app.contourPanel, 'push');
            app.PeriosteumContourButton.ButtonPushedFcn = createCallbackFcn(app, @PeriosteumContourButtonPushed, true);
            app.PeriosteumContourButton.Text = 'Periosteum Contour';
            app.EndosteumContourButton = uibutton(app.contourPanel, 'push');
            app.EndosteumContourButton.ButtonPushedFcn = createCallbackFcn(app, @EndosteumContourButtonPushed, true);
            app.EndosteumContourButton.Text = 'Endosteum Contour';
        end
    end

    methods (Access = public)

        % Construct app
        function app = maskEditorGUI(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end