classdef s_ctGUI_L < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        FileMenu     matlab.ui.container.Menu
        OpenMenu     matlab.ui.container.Menu
        EditMenu     matlab.ui.container.Menu
        CropMenu     matlab.ui.container.Menu
        XYslice      matlab.ui.control.UIAxes
        YZslice      matlab.ui.control.UIAxes
        XZslice      matlab.ui.control.UIAxes
        ZSlider      matlab.ui.control.Slider
        SliderLabel  matlab.ui.control.Label
        XSlider      matlab.ui.control.Slider
        YSlider      matlab.ui.control.Slider
        YSpinner     matlab.ui.control.Spinner
        XSpinner     matlab.ui.control.Spinner
        ZSpinner     matlab.ui.control.Spinner
    end

    % Data Properties
    properties (Access = public)
        XYsliceim       % slice images
        YZsliceim
        XZsliceim
        XYslicen        % slice numbers
        YZslicen
        XZslicen
        XYslicelinex    % slice line plot handles
        XYsliceliney
        XZslicelinex
        XZslicelinez
        YZsliceliney
        YZslicelinez
        sctdataObj      % the input sctdata object
    end

    methods (Access = public)
    
        function plotSlices(app)
            app.plotXYslice;
            app.plotXZslice;
            app.plotYZslice;
        end
        
        % slice plotters
        function plotXYslice(app)
            % plot the slice
            imagesc(app.XYslice, app.sctdataObj.XYslice);
            % imagesc(app.XYslice, app.XYsliceim);
            axis(app.XYslice, 'tight');
            colormap(app.XYslice, 'gray');
            app.XYslice.YDir = 'normal';
            
            % overlap X and Y slice lines
            hold(app.XYslice, 'on');
            app.updateXYslicelineX;
            app.updateXYslicelineY;
        end
        
        function plotXZslice(app)
            imagesc(app.XZslice, app.sctdataObj.XZslice);
            % imagesc(app.XZslice, app.XZsliceim);
            axis(app.XZslice, 'tight');
            colormap(app.XZslice, 'gray');
            app.XZslice.YDir = 'normal';
            app.XZslice.XAxisLocation = 'top';
            
            % overlap X and Z slice lines
            hold(app.XZslice, 'on');
            app.updateXZslicelineX;
            app.updateXZslicelineZ;
        end
        
        function plotYZslice(app)
            imagesc(app.YZslice, app.sctdataObj.YZslice');
            % imagesc(app.YZslice, app.YZsliceim);
            axis(app.YZslice, 'tight');
            colormap(app.YZslice, 'gray');
            app.YZslice.YDir = 'normal';
            app.YZslice.YAxisLocation = 'right';
            
            % overlap Y and Z slice lines
            hold(app.YZslice, 'on');
            app.updateYZslicelineY;
            app.updateYZslicelineZ;
        end
        
        % slice lines plotters
        function updateXYslicelineX(app)
            set(app.XYslicelinex, 'Visible', 'off');
            app.XYslicelinex = plot(app.XYslice, [app.sctdataObj.YZslicen app.sctdataObj.YZslicen], [1 app.sctdataObj.size(2)], 'r');
        end
        
        function updateXYslicelineY(app)
            set(app.XYsliceliney, 'Visible', 'off');
            app.XYsliceliney = plot(app.XYslice, [1 app.sctdataObj.size(1)], [app.sctdataObj.XZslicen app.sctdataObj.XZslicen], 'g');
        end
        
        function updateXZslicelineX(app)
            set(app.XZslicelinex, 'Visible', 'off');
            app.XZslicelinex = plot(app.XZslice, [app.sctdataObj.YZslicen app.sctdataObj.YZslicen], [1 app.sctdataObj.size(3)], 'r');
        end
        
        function updateXZslicelineZ(app)
            set(app.XZslicelinez, 'Visible', 'off');
            app.XZslicelinez = plot(app.XZslice, [1 app.sctdataObj.size(1)], [app.sctdataObj.XYslicen app.sctdataObj.XYslicen], 'b');
        end
        
        function updateYZslicelineY(app)
            set(app.YZsliceliney, 'Visible', 'off');
            app.YZsliceliney = plot(app.YZslice, [1 app.sctdataObj.size(3)], [app.sctdataObj.XZslicen app.sctdataObj.XZslicen], 'g');
        end
        
        function updateYZslicelineZ(app)
            set(app.YZslicelinez, 'Visible', 'off');
            app.YZslicelinez = plot(app.YZslice, [app.sctdataObj.XYslicen app.sctdataObj.XYslicen], [1 app.sctdataObj.size(2)], 'b');
        end
        
        % GUI initialization
        function results = initGUI(app)
            if isempty(app.sctdataObj.data)     return;     end
            
            app.sctdataObj.setOrthoslices;
            app.plotSlices;
            
            app.XSlider.Limits = [1 app.sctdataObj.size(1)];
            app.YSlider.Limits = [1 app.sctdataObj.size(2)];
            app.ZSlider.Limits = [1 app.sctdataObj.size(3)];
            
            app.XSlider.MajorTicks = [1 app.sctdataObj.size(1)];
            app.XSlider.MajorTickLabels = {'1', num2str(app.sctdataObj.size(1))};
            app.YSlider.MajorTicks = [1 app.sctdataObj.size(2)];
            app.YSlider.MajorTickLabels = {'1', num2str(app.sctdataObj.size(2))};
            app.ZSlider.MajorTicks = [1 app.sctdataObj.size(3)];
            app.ZSlider.MajorTickLabels = {'1', num2str(app.sctdataObj.size(3))};
            
            app.XSlider.Value = app.sctdataObj.YZslicen;
            app.XSpinner.Value = app.sctdataObj.YZslicen;
            app.YSlider.Value = app.sctdataObj.XZslicen;
            app.YSpinner.Value = app.sctdataObj.XZslicen;
            app.ZSlider.Value = app.sctdataObj.XYslicen;
            app.ZSpinner.Value = app.sctdataObj.XYslicen;
            
            app.XYslice.Position(3:4) = [app.sctdataObj.size(1:2)./max(app.sctdataObj.size(1:2))]*300;
            app.XZslice.Position(3:4) = [app.sctdataObj.size([1 3])./max(app.sctdataObj.size([1 3]))]*300;
            app.YZslice.Position(3:4) = [app.sctdataObj.size([3 2])./max(app.sctdataObj.size([3 2]))]*300;
            
        end
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, this)
            if nargin < 2
                app.sctdataObj = sctdata;
            else
                app.sctdataObj = this;                      % assign input sctdata to public prop
            
                app.initGUI;    
            end
        end

        % Value changed function: ZSlider
        function ZSliderValueChanged(app, event)
            value = round(app.ZSlider.Value);
            app.sctdataObj.setXYslicen(value);
            app.sctdataObj.setXYslice;
            app.plotXYslice;
            app.updateXZslicelineZ;
            app.updateYZslicelineZ;
            app.ZSpinner.Value = value;
        end

        % Value changed function: XSlider
        function XSliderValueChanged(app, event)
            value = round(app.XSlider.Value);
            app.sctdataObj.setYZslicen(value);
            app.sctdataObj.setYZslice;
            app.plotYZslice;
            app.updateXYslicelineX;
            app.updateXZslicelineX;
            app.XSpinner.Value = value;
        end

        % Value changed function: YSlider
        function YSliderValueChanged(app, event)
            value = round(app.YSlider.Value);
            app.sctdataObj.setXZslicen(value);
            app.sctdataObj.setXZslice;
            app.plotXZslice;
            app.updateXYslicelineY;
            app.updateYZslicelineY;
            app.YSpinner.Value = value;
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            app.sctdataObj.load;
            
            app.initGUI;
        end

        % Value changed function: YSpinner
        function YSpinnerValueChanged(app, event)
            value = app.YSpinner.Value;
            app.sctdataObj.setXZslicen(value);
            app.sctdataObj.setXZslice;
            app.plotXZslice;
            app.updateXYslicelineY;
            app.updateYZslicelineY;
            app.YSlider.Value = value;
        end

        % Value changed function: XSpinner
        function XSpinnerValueChanged(app, event)
            value = app.XSpinner.Value;
            app.sctdataObj.setYZslicen(value);
            app.sctdataObj.setYZslice;
            app.plotYZslice;
            app.updateXYslicelineX;
            app.updateXZslicelineX;
            app.XSlider.Value = value;
        end

        % Value changed function: ZSpinner
        function ZSpinnerValueChanged(app, event)
            value = app.ZSpinner.Value;
            app.sctdataObj.setXYslicen(value);
            app.sctdataObj.setXYslice;
            app.plotXYslice;
            app.updateXZslicelineZ;
            app.updateYZslicelineZ;
            app.ZSlider.Value = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Accelerator = 'o';
            app.OpenMenu.Text = 'Open';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.CropMenu = uimenu(app.EditMenu);
            app.CropMenu.Text = 'Crop';
            app.XYslice = uiaxes(app.UIFigure);
            xlabel(app.XYslice, 'X (pixels)')
            ylabel(app.XYslice, 'Y (pixels)')
            app.YZslice = uiaxes(app.UIFigure);
            xlabel(app.YZslice, 'Z (pixels)')
            app.XZslice = uiaxes(app.UIFigure);
            ylabel(app.XZslice, 'Z (pixels)')
            app.ZSlider = uislider(app.UIFigure);
            app.ZSlider.Limits = [1 100];
            app.ZSlider.MajorTicks = [1 11 21 31 41 51 61 71 81 100];
            app.ZSlider.MajorTickLabels = {'1', '', '', '', '', '', '', '', '', '100'};
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliderValueChanged, true);
            app.ZSlider.Value = 1;
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = '';
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.Limits = [1 100];
            app.XSlider.MajorTicks = [1 11 21 31 41 51 61 71 81 100];
            app.XSlider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.XSlider.Value = 1;
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.Limits = [1 100];
            app.YSlider.MajorTicks = [1 11 21 31 41 51 61 71 81 100];
            app.YSlider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @YSliderValueChanged, true);
            app.YSlider.MinorTicks = [];
            app.YSlider.Value = 1;
            app.YSpinner = uispinner(app.UIFigure);
            app.YSpinner.Limits = [1 Inf];
            app.YSpinner.RoundFractionalValues = 'on';
            app.YSpinner.ValueDisplayFormat = '%.0f';
            app.YSpinner.ValueChangedFcn = createCallbackFcn(app, @YSpinnerValueChanged, true);
            app.YSpinner.Value = 1;
            app.XSpinner = uispinner(app.UIFigure);
            app.XSpinner.Limits = [1 Inf];
            app.XSpinner.RoundFractionalValues = 'on';
            app.XSpinner.ValueDisplayFormat = '%.0f';
            app.XSpinner.ValueChangedFcn = createCallbackFcn(app, @XSpinnerValueChanged, true);
            app.XSpinner.Value = 1;
            app.ZSpinner = uispinner(app.UIFigure);
            app.ZSpinner.Limits = [1 Inf];
            app.ZSpinner.RoundFractionalValues = 'on';
            app.ZSpinner.ValueDisplayFormat = '%.0f';
            app.ZSpinner.ValueChangedFcn = createCallbackFcn(app, @ZSpinnerValueChanged, true);
            app.ZSpinner.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = s_ctGUI_L(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end